classdef GUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1    matlab.ui.Figure
        uipanel1   matlab.ui.container.Panel
        text1      matlab.ui.control.Label
        text2      matlab.ui.control.Label
        text3      matlab.ui.control.Label
        text6      matlab.ui.control.Label
        text4      matlab.ui.control.Label
        text5      matlab.ui.control.Label
        density    matlab.ui.control.EditField
        volume     matlab.ui.control.EditField
        mass       matlab.ui.control.Label
        unitgroup  matlab.ui.container.ButtonGroup
        english    matlab.ui.control.RadioButton
        si         matlab.ui.control.RadioButton
        calculate  matlab.ui.control.Button
        reset      matlab.ui.control.Button
    end

    
    methods (Access = private)
        function initialize_gui(app, fig_handle, handles, isreset)
            % If the metricdata field is present and the reset flag is false, it means
            % we are we are just re-initializing a GUI by calling it from the cmd line
            % while it is up. So, bail out as we dont want to reset the data.
            if isfield(handles, 'metricdata') && ~isreset
                return;
            end
            
            app.metricdata.density = 0;
            app.metricdata.volume  = 0;
            
            set(app.density, 'String', app.metricdata.density);
            set(app.volume,  'String', app.metricdata.volume);
            set(app.mass, 'String', 0);
            
            set(app.unitgroup, 'SelectedObject', app.english);
            
            set(app.text4, 'String', 'lb/cu.in');
            set(app.text5, 'String', 'cu.in');
            set(app.text6, 'String', 'lb');
            
            % Update handles structure
            guidata(app.figure1, handles);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function GUI_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to GUI (see VARARGIN)
%             
%             % Choose default command line output for GUI
%             app.output = hObject;
%             
%             % Update handles structure
%             guidata(hObject, handles);
%             
%             initialize_gui(hObject, handles, false);
        end

        % Button pushed function: calculate
        function calculate_Callback(app, event)
            % hObject    handle to calculate (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            mass = app.metricdata.density * app.metricdata.volume;
            set(app.mass, 'String', mass);
        end

        % Value changed function: density
        function density_Callback(app, event)
            % hObject    handle to density (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of density as text
            %        str2double(get(hObject,'String')) returns contents of density as a double
            density = str2double(get(hObject, 'String'));
            if isnan(density)
                set(hObject, 'String', 0);
                errordlg('Input must be a number','Error');
            end
            
            % Save the new density value
            app.metricdata.density = density;
            guidata(hObject,handles)
        end

        % Button pushed function: reset
        function reset_Callback(app, event)
            % hObject    handle to reset (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            initialize_gui(gcbf, handles, true);
        end

        % Selection changed function: unitgroup
        function unitgroup_SelectionChangedFcn(app, event)
            % hObject    handle to the selected object in unitgroup
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            if (hObject == app.english)
                set(app.text4, 'String', 'lb/cu.in');
                set(app.text5, 'String', 'cu.in');
                set(app.text6, 'String', 'lb');
            else
                set(app.text4, 'String', 'kg/cu.m');
                set(app.text5, 'String', 'cu.m');
                set(app.text6, 'String', 'kg');
            end
        end

        % Value changed function: volume
        function volume_Callback(app, event)
            % hObject    handle to volume (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of volume as text
            %        str2double(get(hObject,'String')) returns contents of volume as a double
            volume = str2double(get(hObject, 'String'));
            if isnan(volume)
                set(hObject, 'String', 0);
                errordlg('Input must be a number','Error');
            end
            
            % Save the new volume value
            app.metricdata.volume = volume;
            guidata(hObject,handles)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Untitled';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Measures ';
            app.text1 = uilabel(app.uipanel1);
            app.text1.Text = 'Density(D):';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Text = 'Volume(V):';
            app.text3 = uilabel(app.uipanel1);
            app.text3.Text = 'Mass(D*V):';
            app.text6 = uilabel(app.uipanel1);
            app.text6.Text = 'lb';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Text = 'lb/cu.in';
            app.text5 = uilabel(app.uipanel1);
            app.text5.Text = 'cu.in';
            app.density = uieditfield(app.uipanel1, 'text');
            app.density.ValueChangedFcn = createCallbackFcn(app, @density_Callback, true);
            app.density.Value = '0';
            app.volume = uieditfield(app.uipanel1, 'text');
            app.volume.ValueChangedFcn = createCallbackFcn(app, @volume_Callback, true);
            app.volume.Value = '0';
            app.mass = uilabel(app.uipanel1);
            app.mass.Enable = 'off';
            app.mass.Text = '0';
            app.unitgroup = uibuttongroup(app.figure1);
            app.unitgroup.SelectionChangedFcn = createCallbackFcn(app, @unitgroup_SelectionChangedFcn, true);
            app.unitgroup.Title = 'Units';
            app.english = uiradiobutton(app.unitgroup);
            app.english.Text = 'English Unit System';
            app.english.Value = true;
            app.si = uiradiobutton(app.unitgroup);
            app.si.Text = 'S.I. Unit System';
            app.calculate = uibutton(app.figure1, 'push');
            app.calculate.ButtonPushedFcn = createCallbackFcn(app, @calculate_Callback, true);
            app.calculate.Text = 'Calculate';
            app.reset = uibutton(app.figure1, 'push');
            app.reset.ButtonPushedFcn = createCallbackFcn(app, @reset_Callback, true);
            app.reset.Text = 'Reset';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @GUI_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end